classdef PRUEBA_INTERFACE_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComunicacionTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ConexionTab                     matlab.ui.container.Tab
        UNIVERSIDADDELASFUERZASARMADASESPELabel_2  matlab.ui.control.Label
        DireccionIPRobotLabel           matlab.ui.control.Label
        ip_Robot                        matlab.ui.control.EditField
        VelocidadTopicEditFieldLabel    matlab.ui.control.Label
        Velociad_topic                  matlab.ui.control.EditField
        OdometriaTopicEditFieldLabel    matlab.ui.control.Label
        Odometria_topic                 matlab.ui.control.EditField
        Conexion_robot                  matlab.ui.control.Button
        Desconexion_robot               matlab.ui.control.Button
        DireccionIPComputadorLabel      matlab.ui.control.Label
        ip_Computador                   matlab.ui.control.EditField
        LaserTopicEditFieldLabel        matlab.ui.control.Label
        Laser_topic                     matlab.ui.control.EditField
        ProporcionalTab                 matlab.ui.container.Tab
        Proporcional                    matlab.ui.container.TabGroup
        Ejecucion_proporcional          matlab.ui.container.Tab
        ControlProporcionalCompensacionDinamicaLabel  matlab.ui.control.Label
        Movimiento_proporcional         matlab.ui.control.UIAxes
        T_s_proporcional                matlab.ui.control.NumericEditField
        SampleTimeLabel                 matlab.ui.control.Label
        Inicio_proporcional             matlab.ui.control.Button
        FinalTimeEditFieldLabel         matlab.ui.control.Label
        T_final_proporcional            matlab.ui.control.NumericEditField
        DeseadoenXLabel                 matlab.ui.control.Label
        x_deseado_proporcional          matlab.ui.control.EditField
        DeseadoenYLabel                 matlab.ui.control.Label
        y_deseado_proporcional          matlab.ui.control.EditField
        EstadoLabel                     matlab.ui.control.Label
        Estado_proporcional             matlab.ui.control.Lamp
        Detener_proporcional            matlab.ui.control.Button
        Resultados_proporcional         matlab.ui.container.Tab
        Errores_posicion_proporcional   matlab.ui.control.UIAxes
        Errores_velocidad_proporcional  matlab.ui.control.UIAxes
        Velocidades_reales_proporcional  matlab.ui.control.UIAxes
        Velocidades_dinamica_proporcional  matlab.ui.control.UIAxes
        FuzzyTab                        matlab.ui.container.Tab
        Fuzzy                           matlab.ui.container.TabGroup
        Ejecucion_fuzzy                 matlab.ui.container.Tab
        ControlFuzzyAdaptativoCompensacionDinamicaLabel  matlab.ui.control.Label
        Movimiento_fuzzy                matlab.ui.control.UIAxes
        T_s_fuzzy                       matlab.ui.control.NumericEditField
        SampleTimeLabel_2               matlab.ui.control.Label
        Inicio_fuzzy                    matlab.ui.control.Button
        FinalTimeEditFieldLabel_2       matlab.ui.control.Label
        T_final_fuzzy                   matlab.ui.control.NumericEditField
        DeseadoenXLabel_2               matlab.ui.control.Label
        x_deseado_fuzzy                 matlab.ui.control.EditField
        DeseadoenYLabel_2               matlab.ui.control.Label
        y_deseado_fuzzy                 matlab.ui.control.EditField
        EstadoLabel_2                   matlab.ui.control.Label
        Estado_fuzzy                    matlab.ui.control.Lamp
        Detener_fuzzy                   matlab.ui.control.Button
        Resultados_fuzzy                matlab.ui.container.Tab
        Errores_posicion_fuzzy          matlab.ui.control.UIAxes
        Errores_velocidad_fuzzy         matlab.ui.control.UIAxes
        Velocidades_reales_fuzzy        matlab.ui.control.UIAxes
        Velocidades_dinamica_fuzzy      matlab.ui.control.UIAxes
        Parametros_fuzzy                matlab.ui.container.Tab
        Adaptacion_proporcional_fuzzy   matlab.ui.control.UIAxes
        Adaptacion_derivativo_fuzzy     matlab.ui.control.UIAxes
        OptimizacionTab                 matlab.ui.container.Tab
        Optimo                          matlab.ui.container.TabGroup
        Ejecucion_optimo                matlab.ui.container.Tab
        ControlOptimoCompensacionDinamicaLabel  matlab.ui.control.Label
        Movimiento_optimo               matlab.ui.control.UIAxes
        T_s_optimo                      matlab.ui.control.NumericEditField
        SampleTimeLabel_3               matlab.ui.control.Label
        Inicio_optimo                   matlab.ui.control.Button
        FinalTimeEditFieldLabel_3       matlab.ui.control.Label
        T_final_optimo                  matlab.ui.control.NumericEditField
        DeseadoenXLabel_3               matlab.ui.control.Label
        x_deseado_optimo                matlab.ui.control.EditField
        DeseadoenYLabel_3               matlab.ui.control.Label
        y_deseado_optimo                matlab.ui.control.EditField
        EstadoLabel_3                   matlab.ui.control.Label
        Estado_optimo                   matlab.ui.control.Lamp
        Detener_optimo                  matlab.ui.control.Button
        Resultados_optimo               matlab.ui.container.Tab
        Errores_posicion_optimo         matlab.ui.control.UIAxes
        Errores_velocidad_optimo        matlab.ui.control.UIAxes
        Velocidades_reales_optimo       matlab.ui.control.UIAxes
        Velocidades_dinamica_optimo     matlab.ui.control.UIAxes
        NeuronalTab                     matlab.ui.container.Tab
        EvasiondeObstaculosTab          matlab.ui.container.Tab
        ManualTab                       matlab.ui.container.Tab
    end


    methods (Access = private)
    
        function cinematico= PROPORCIONAL(app,K1,K2,hd,hdp,h,q,a)
            he=hd-h;
            phi=q(2);
            K1=K1*diag([1 1]);
            K2=K2*diag([1 1]);
            J11=cos(phi);
            J12=-a*sin(phi);
            J21=sin(phi);
            J22=a*cos(phi);
            J=[J11 J12;
            J21 J22];
            %% LEY DE CONTROL SEGUIMIENTO DE TRAYECTORIA
            cinematico=inv(J)*(hdp+K2*tanh(K2^(-1)*K1*he));
        end
        
        function Dinamica = COMPENSACION_DINAMICA_PLATAFORMA_MOVIL_N(app,vrefp,vref_e,v,q,ts,PARAMETROS)


            % a) Velocidades de la plataforma móvil y del brazo robótico
            us = v(1);
            ws = v(2);

     
            % b) Estados de la plataforma móvil y del brazo robótico
            ths = q(2);

            % c) Parámetros Dinámicos de la plataforma movil

            C1=PARAMETROS(1);
            C2=PARAMETROS(2);
            C3=PARAMETROS(3);
            C4=PARAMETROS(4);
            C5=PARAMETROS(5);
            C6=PARAMETROS(6);
            C7=PARAMETROS(7);
    
            % d) Matriz de Inercia
            M11 = C1;
            M12 = 0;
    
            M21 = 0;
            M22 = C2;
    
            M = [M11 M12;
                M21 M22];
 
            % e) Matriz de Fuerzas Centrípetas y de Coriolis
            Cs11 = C3;
            Cs12 = C4+C5*ws;
    
            Cs21 =C6*ws;
            Cs22 =C7;
     
            C = [Cs11 Cs12;
                Cs21 Cs22];
            % f) MatrizCompensacion Dinamica.
            K3=1*diag([1 1]);

            K4=0.5*diag([1 1]);

            % h) Modelo para compensación dinámica 
            vref= M*(vrefp+K4*tanh((K4^(-1))*K3*vref_e))+C*v;
            us = vref(1);
            ws = vref(2);

            v_1 = [us ws]';
   
            Dinamica = [v_1];
        end
        
        function w_filtrada = Filtro(app,w,w_1)
            %UNTITLED5 Summary of this function goes here
            %   Detailed explanation goes 
            variacion=abs(w-w_1);

            if variacion>2
                w_filtrada=w_1;
    
            else
                w_filtrada=w;
            end

        end
        
        function world = World(app,lidarSub,pose)
            th = pose(3)-pi/2;
            scanMsg = receive(lidarSub);
            data=readCartesian(scanMsg)*[0 1; -1 0];
            world=data*[cos(th) sin(th);-sin(th) cos(th)] + repmat(pose(1:2),[numel(data(:,1)),1]);
            
        end
        
        function  [Kp,Kd]= FUZZY_ADAPTATIVO(app,self_x,self_y,hd,hdp,h,hp)
            he=hd-h;
            hep=hdp-hp;
            % CALCULPO DE LAS GANANCIAS
            K_x=evalfis(tanh([he(1),hep(1)]),self_x);
            K_y=evalfis(tanh([he(2),hep(2)]),self_y);
            Kp=[K_x(1) K_y(1)]';
            Kd=[K_x(2) K_y(2)]';
        end
        
        function cinematico= PROPORCIONAL_DERIVATIVO(app,K1,K3,K2,hd,hdp,h,hp,q,a,si)
            he=hd-h;
     
            hep=hdp-hp;
     
            phi=q(2);
     
            K1=diag(K1);
     
            K3=diag(K3);
     
     
            K2=K2*diag([1 1]);
     
            J11=cos(phi);
     
            J12=-a*sin(phi);
     
            J21=sin(phi);
     
            J22=a*cos(phi);
     
            J=[J11 J12;
                J21 J22];
    
            s=he+K2*si;
            %% LEY DE CONTROL SEGUIMIENTO DE TRAYECTORIA
            %cinematico=inv(J)*(hdp+K2*tanh(K2^-1*K1*he)+K2*tanh(K2^-1*K3*hep));
            cinematico=inv(J)*(hdp+K2*tanh(K2^-1*K1*he));
        end
        
        function [L] = movil_optimo(app,z,H,hd,hd1,h,q,l,ts)
        %1) Definici�n de variables
            %a) Acciones de control (incognita)        
             v_1 = z;       %z=[u,w,q1_p,q2_p,q3_p,q4_p]';
        
            %b) Estados de control
            th = q(2);
          
            
            %c) Par�mtros del Manipulador M�vil
             a = l(1);
         
        
        %2) Matriz Jacobiana
            j11 = cos(th);
            j12 = -a*sin(th);
           
                
            j21 = sin(th);
            j22 = +a*cos(th);


            J = [j11 j12; 
                 j21 j22];
            
        %2) Posiciones deseados k+1    
            hk1 = ts*J*v_1+h;
            
            R=[1,0;0,1];
            
            error_1=hd-h;
            
            error=hd1-hk1;
            
            rate_error=error-error_1;
            
        %3) Integraci�n del Funcional        
            L = rate_error'*H*rate_error+error'*R*error; %Trapecio
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc,clear all,close all;
            global conexion_bandera desconexion_bandera PARAMETROS;
            global ejecucion_bandera_proporcional detener_bandera_proporcional; 
            global ejecucion_bandera_fuzzy detener_bandera_fuzzy;
            global ejecucion_bandera_optimo detener_bandera_optimo;
            
            %% declaracion de valores iniciales de las banderas de ejecucion del proporcional
            ejecucion_bandera_proporcional=0;
            detener_bandera_proporcional=0;
            
            %% declaracion de los valores iniciales de las banderas de ejecucion del fuzzy
            ejecucion_bandera_fuzzy=0;
            detener_bandera_fuzzy=0;
            
            %% declaracion de los valores iniciales de las banderas de ejecucion del fuzzy
            ejecucion_bandera_optimo=0;
            detener_bandera_optimo=0;
            
            %% declaracion de las variables para la comunicacion
            conexion_bandera=0;
            desconexion_bandera=0;
            
            %% cargar datos de la dinamica del sistema
            load('DINAMICA_PLATAFORMA');
            PARAMETROS=x;
        end

        % Callback function
        function ButtonPushed(app, event)
            app.SAMPLE_TIMEEditField.Value=25;
        end

        % Button pushed function: Inicio_proporcional
        function Inicio_proporcionalButtonPushed(app, event)
            %% variables globales propias del controlador
            global ip_robot ip_pc cmd_r odom_r laser_r conexion_bandera robot velmsg odom lidarSub PARAMETROS;
            
            global ejecucion_bandera_proporcional detener_bandera_proporcional;
            
            %% variables globales para evitar confunsion con los demas controladores
            global ejecucion_bandera_fuzzy ejecucion_bandera_optimo;
            
            %% Variables auxiliares para los datos de trayectoria verificacion de que se colocaran
            auxiliar1=isempty(app.x_deseado_proporcional.Value);
            
            auxiliar2=isempty(app.y_deseado_proporcional.Value);
            
            %% variables auxiliares para los datos de comunicacion
            inicializacion_auxiliar1=isempty(ip_robot);
            
            inicializacion_auxiliar2=isempty(cmd_r);
            
            inicializacion_auxiliar3=isempty(odom_r);
            
            inicializacion_auxiliar4=isempty(ip_pc);
            
            inicializacion_auxiliar5=isempty(laser_r);
            
            %% Definicion de los tiempos de la simulacion
            
            ts=app.T_s_proporcional.Value;
            
            tfinal=app.T_final_proporcional.Value;
            
            t=[0:ts:tfinal];
            
            auxiliar3=ones(1,length(t));
            
            %% Condiciones para iniciar el programa de control
          
            if(auxiliar1==1)||(auxiliar2==1)
                mensaje_inicio='Colocar los parametros de trayectarias o tiempos';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de trayectorias o tiempos");
                
            elseif(inicializacion_auxiliar1==1)||(inicializacion_auxiliar2==1)||(inicializacion_auxiliar3==1)||(inicializacion_auxiliar4==1)||(inicializacion_auxiliar5==1) 
                
                mensaje_inicio='Colocar los parametros de comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de comunicacion");
                
            elseif (conexion_bandera==0)
                
                mensaje_inicio='El Robot no ha establecido Comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("El Robot no ha establecido Comunicacion");
                
            elseif (ejecucion_bandera_fuzzy==1)||(ejecucion_bandera_optimo==1)||(ejecucion_bandera_proporcional==1)
                mensaje_inicio='Accion no permitida en ejecucion otro controlador';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Accion no permitida en ejecucion otro controlador");
            else
                %% LECTURA DEL ROBOT PARA CONDICIONES INICIALES
                odomdata = receive(odom,3);
                pose = odomdata.Pose.Pose;
                vel=odomdata.Twist.Twist;
                quat = pose.Orientation;
                angles = quat2eul([quat.W quat.X quat.Y quat.Z]);

                %% DISTANCIA HACIA EL PUNTO DE INTERES
                a=0.1;

                %% POSICIONES INICIALES 
                hx(1)=pose.Position.X;
                hy(1)=pose.Position.Y;
                phi(1)=angles(1);
                
                %% VELOCIDADES DE CONTROL INICIALES REALES
                u(1) = vel.Linear.X;
                w(1) = vel.Angular.Z;

                %% CINEMATICA DIRECTA 
                hx(1)=hx(1)+a*cos(phi(1));
                hy(1)=hy(1)+a*sin(phi(1));

                %% VELOCIDADES EN CADA EJE DEL ROBOT INICIALES
                hxp(1)=u(1)*cos(phi(1))-a*w(1)*sin(phi(1));
                hyp(1)=u(1)*sin(phi(1))+a*w(1)*cos(phi(1));
                
                
                %% TRAYECTORIA DESEADAS
                hxd=eval(app.x_deseado_proporcional.Value).*auxiliar3;
                
                hyd=eval(app.y_deseado_proporcional.Value).*auxiliar3;
                
                hxdp=[0 diff(hxd)/ts];
                
                hydp=[0 diff(hyd)/ts];
                
                %% GANANCIASfigure DEL CONTROLADOR CINEMATICO
                KP=1; 

                %% GANANCIA PARA LOS ACTUADORES
                K2=1; 
                
                cla(app.Movimiento_proporcional,"reset");
                
                cla(app.Errores_posicion_proporcional,"reset");
                
                cla(app.Errores_velocidad_proporcional,"reset");
                
                cla(app.Velocidades_reales_proporcional,"reset");
                
                cla(app.Velocidades_dinamica_proporcional,"reset");
                
                %% Definicion de las propiedades de la grafica Movimeinto
            
                title(app.Movimiento_proporcional,'$\textrm{Trayectoria Deseada y Trayectoria Descrita}$','Interpreter','latex','FontSize',12);
                app.Movimiento_proporcional.XMinorGrid='on';
                app.Movimiento_proporcional.YMinorGrid='on';
                xlabel(app.Movimiento_proporcional,'$\textrm{X}[m]$','Interpreter','latex','FontSize',9); 
                ylabel( app.Movimiento_proporcional,'$\textrm{Y}[m]$','Interpreter','latex','FontSize',9);
                app.Movimiento_proporcional.XLim=[-1 1];
                app.Movimiento_proporcional.YLim=[-1 1];
                ph1=plot(app.Movimiento_proporcional,nan,nan,'g',nan,nan,'b');
                
                
                %% Definicion de las propiedades de la grafica de los errores de posicion
                title(app.Errores_posicion_proporcional,'$\textrm{Errores de Posicion}$','Interpreter','latex','FontSize',12);
                app.Errores_posicion_proporcional.XMinorGrid='on';
                app.Errores_posicion_proporcional.YMinorGrid='on';
                xlabel(app.Errores_posicion_proporcional,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_posicion_proporcional,'$[m]$','Interpreter','latex','FontSize',9);
                app.Errores_posicion_proporcional.XLim=[0 t(end)];
                ph2=plot(app.Errores_posicion_proporcional,nan,nan,'r',nan,nan,'g');
                
                
                %% Definicion de las propiedades de la grafica de los errores de velocidad
                title(app.Errores_velocidad_proporcional,'$\textrm{Errores de Velocidad}$','Interpreter','latex','FontSize',12);
                app.Errores_velocidad_proporcional.XMinorGrid='on';
                app.Errores_velocidad_proporcional.YMinorGrid='on';
                xlabel(app.Errores_velocidad_proporcional,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_velocidad_proporcional,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Errores_velocidad_proporcional.XLim=[0 t(end)];
                ph3=plot(app.Errores_velocidad_proporcional,nan,nan,'r',nan,nan,'b');
                
                %% Definicion de las propiedades de la grafica de las velocidades de control y reales
                
                title(app.Velocidades_reales_proporcional,'$\textrm{Velocidades a la Salida del Robot y Velocidades de Control Cinematico}$','Interpreter','latex','FontSize',12);
                app.Velocidades_reales_proporcional.XMinorGrid='on';
                app.Velocidades_reales_proporcional.YMinorGrid='on';
                xlabel(app.Velocidades_reales_proporcional,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_reales_proporcional,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_reales_proporcional.XLim=[0 t(end)];
                ph4=plot(app.Velocidades_reales_proporcional,nan,nan,'r',nan,nan,'b',nan,nan,'--r',nan,nan,'--b');
                
                %% Definicion de las propiedades de la grafica de la compensacion dinamica
                
                
                title(app.Velocidades_dinamica_proporcional,'$\textrm{Velocidades de Compensacion Dinamica}$','Interpreter','latex','FontSize',12);
                app.Velocidades_dinamica_proporcional.XMinorGrid='on';
                app.Velocidades_dinamica_proporcional.YMinorGrid='on';
                xlabel(app.Velocidades_dinamica_proporcional,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_dinamica_proporcional,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_dinamica_proporcional.XLim=[0 t(end)];
                ph5=plot(app.Velocidades_dinamica_proporcional,nan,nan,'r',nan,nan,'g');


                xdata=[];
                ydata=[];
                
                xd_data=[];
                yd_data=[];
                
                
%                 world=World(app,lidarSub,[hx(1),hy(1),phi(1)]);
                
                for k=1:length(t)
                    %% Auxiliar para no poder desconcectar durante la ejecucion
                    ejecucion_bandera_proporcional=1;
                    if(detener_bandera_proporcional==1)
                        mensaje_inicio='El Robot se ha detenido';
                        msgbox(mensaje_inicio,'Aviso Robot');
                        disp("El Robot se ha Desconectado");
                       break; 
                    end
                    
                    %% Parte para iluminar led
                    app.Estado_proporcional.Color = [0 1 0];
                    
                    %% Parte para obtener el timepo de muestreo
                    tic;
                    
                    %% auxiliar para las graficas si surgue un problema
                    t_auxiliar(k)=t(k);
                    
                    %% ERROR DE CONTROL
                    hxe(k)=hxd(k)-hx(k);
    
                    hye(k)=hyd(k)-hy(k);
    
                    %% VECTORES PARA FORMA GENERAL
                    hd=[hxd(k) hyd(k)]';
    
                    hdp=[hxdp(k) hydp(k)]';
    
                    h =[hx(k),hy(k)]';
    
                    hp=[hxp(k),hyp(k)]';
    
                    q=[0 phi(k)]'; 
    
                    %% CONTROLADOR CINEMATICO
                    qref=PROPORCIONAL(app,KP,K2,hd,hdp,h,q,a); 
                    
                    %% VELOCIDADES CINEMATICAS O VELOCIDADES DESEADAS PARA EL BLOQUE DE COMPENSACIÓN DIN�?MICA
                    uref_c(k)=qref(1);
                    wref_c(k)=qref(2);
    
                    %% ERRORES DE VELOCIDAD VREF
                    ue(k)=uref_c(k)-u(k);
                    we(k)=wref_c(k)-w(k);
                    vref_e=[ue(k) we(k)]';
    
                    %% DERIVADAS DE LAS VREF DESEADAS
                    vrefp_u=diff([uref_c uref_c(end)])/ts;
                    vrefp_w=diff([wref_c wref_c(end)])/ts;
                    vrefp=[vrefp_u(k) vrefp_w(k)]';
                    v=[uref_c(k) wref_c(k)]';
    
                    %% COMPENSACION DINAMICA PLATAFORMA MOVIL
                    Dinamica = COMPENSACION_DINAMICA_PLATAFORMA_MOVIL_N(app,vrefp,vref_e,v,q,ts,PARAMETROS);
    
                    %% DINAMICA DE LA PLATAFORMA MOVIL
                    uref(k)=Dinamica(1);
                    wref(k)=Dinamica(2);

                    %% ENVIO DE DATOS AL ROBOT
                    velmsg.Linear.X = uref(k);
                    velmsg.Angular.Z =wref(k);
                    send(robot,velmsg);
    
                    %% LECTURA DE LAS POSICIONES DEL ROBOT
                    odomdata = receive(odom,3);
                    pose = odomdata.Pose.Pose;
                    quat = pose.Orientation;
                    angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
    
                    %% LECTURA DE LAS VELOCIDADES DE CONTROL REALES DEL ROBOT
                    vel=odomdata.Twist.Twist;
    
                    %% VELOCIDADES DEL ROBOT
                    u(k+1)=vel.Linear.X;
                    aux=vel.Angular.Z;
                    w(k+1)=Filtro(app,vel.Angular.Z,w(k));
   
                    %% CINEMATICA DIRECTA
                    phi(k+1)=angles(1);
     
                    hxp(k+1)=u(k+1)*cos(phi(k+1))-a*w(k+1)*sin(phi(k+1));
                    hyp(k+1)=u(k+1)*sin(phi(k+1))+a*w(k+1)*cos(phi(k+1));
     
                    hx(k+1)=pose.Position.X+a*cos(phi(k+1));
                    hy(k+1)=pose.Position.Y+a*sin(phi(k+1));
                    
%                     world=World(app,lidarSub,[hx(k+1),hy(k+1),phi(k+1)]);
                    
                    %% GRAFICAS SISTEMA
                    xdata=[xdata hx(k)];
                
                    ydata=[ydata hy(k)];
                    
                    xd_data=[xd_data hxd(k)];
                    
                    yd_data=[yd_data hyd(k)];


%                     xworld=[world(:,1)]';
%                     yworld=[world(:,2)]';
                    
                    %% GRAFICAS DE LA POSICION
                    set(ph1(1),'XData',xd_data,'YData',yd_data);
                    set(ph1(2),'XData',xdata,'YData',ydata);
%                     set(ph1(3),'XData',xworld,'YData',yworld);
                    legend(app.Movimiento_proporcional,{'$\mathbf{\eta}_{p_{des}}$','$\mathbf{\eta}_{p}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                    legend(app.Movimiento_proporcional,'boxoff')
                    
                   
                    drawnow;
                    while(toc<ts)
                    end
                    toc
                    t_sample(k)=toc;
                end
                %% MENSAJE PARA IR A RESULTADOS
                mensaje_inicio='Ir a resultados';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Ir a resultados");
                %% GRAFICAS DE LOS ERRORES DE POSICION
                set(ph2(1),'XData',t_auxiliar,'YData',hxe(1:length(t_auxiliar)));
                set(ph2(2),'XData',t_auxiliar,'YData',hye(1:length(t_auxiliar)));
                legend(app.Errores_posicion_proporcional,{'$\tilde{x_{p}}$','$\tilde{y_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_posicion_proporcional,'boxoff');
                
                %% GRAFICAS DE LOS ERRORES DE VELOCIDAD
                set(ph3(1),'XData',t_auxiliar,'YData',ue(1:length(t_auxiliar)));
                set(ph3(2),'XData',t_auxiliar,'YData',we(1:length(t_auxiliar)));
                legend(app.Errores_velocidad_proporcional,{'$\tilde\mu$','$\tilde{\dot\psi_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_velocidad_proporcional,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES REALES Y LA DEL CONTROLADOR
                set(ph4(1),'XData',t_auxiliar,'YData',u(1:length(t_auxiliar)));
                set(ph4(2),'XData',t_auxiliar,'YData',w(1:length(t_auxiliar)));
                set(ph4(3),'XData',t_auxiliar,'YData',uref_c(1:length(t_auxiliar)));
                set(ph4(4),'XData',t_auxiliar,'YData',wref_c(1:length(t_auxiliar)));
                legend(app.Velocidades_reales_proporcional,{'$\mu$','$\dot\psi_{p}$','$\mu_{ref_{c}}$','$\dot\psi_{p_{ref_{c}}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_reales_proporcional,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES DE COMPENSACION DINAMICA
                set(ph5(1),'XData',t_auxiliar,'YData',uref(1:length(t_auxiliar)));
                set(ph5(2),'XData',t_auxiliar,'YData',wref(1:length(t_auxiliar)));
                legend(app.Velocidades_dinamica_proporcional,{'$\mu_{ref}$','$\dot\psi_{p_{ref}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_dinamica_proporcional,'boxoff');
                
                %% Desactivar led al acabar el programa
                app.Estado_proporcional.Color = [0.149 0.149 0.149];
                
                velmsg.Linear.X = 0;
                velmsg.Angular.Z = 0;
                send(robot,velmsg);
                
                ejecucion_bandera_proporcional=0;
                detener_bandera_proporcional=0;
            end  
        end

        % Callback function
        function Deseado_xValueChanged(app, event)
            value = app.Deseado_x.Value;
        end

        % Callback function
        function Deseado_yValueChanged(app, event)
            value = app.Deseado_y.Value;
        end

        % Value changed function: x_deseado_proporcional
        function x_deseado_proporcionalValueChanged(app, event)
            value = app.x_deseado_proporcional.Value;         
        end

        % Value changed function: y_deseado_proporcional
        function y_deseado_proporcionalValueChanged(app, event)
            value = app.y_deseado_proporcional.Value;
        end

        % Value changed function: ip_Robot
        function ip_RobotValueChanged(app, event)
            value = app.ip_Robot.Value;
            global ip_robot 
            ip_robot=value;
        end

        % Value changed function: Velociad_topic
        function Velociad_topicValueChanged(app, event)
            value = app.Velociad_topic.Value;
            global cmd_r
            cmd_r=value;
        end

        % Value changed function: Odometria_topic
        function Odometria_topicValueChanged(app, event)
            value = app.Odometria_topic.Value;
            global  odom_r
            odom_r=value;
        end

        % Button pushed function: Conexion_robot
        function Conexion_robotButtonPushed(app, event)
            global conexion_bandera desconexion_bandera robot velmsg odom lidarSub ip_robot ip_pc cmd_r odom_r laser_r;
            global ejecucion_bandera_proporcional ejecucion_bandera_fuzzy ejecucion_bandera_optimo;
            
            conexion_bandera=0;
            
            desconexion_bandera=0;
            
            inicializacion_auxiliar1=isempty(ip_robot);
            
            inicializacion_auxiliar2=isempty(cmd_r);
            
            inicializacion_auxiliar3=isempty(odom_r);
            
            inicializacion_auxiliar4=isempty(ip_pc);
  
            inicializacion_auxiliar5=isempty(laser_r);
            
            if(inicializacion_auxiliar1==1)||(inicializacion_auxiliar2==1)||(inicializacion_auxiliar3==1)||(inicializacion_auxiliar4==1)||(inicializacion_auxiliar5==1) 
                mensaje_inicio='Colocar los parametros faltantes de la comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros faltantes de la comunicacion");
                desconexion_bandera=0;
            else
                if (strcmp(cmd_r,'/cmd_vel')==1) && (strcmp(odom_r,'/odom')==1) && (strcmp(laser_r,'/scan')==1 && (ejecucion_bandera_proporcional==0)&&(ejecucion_bandera_fuzzy==0)&&(ejecucion_bandera_optimo==0)) 
                    
                    rosshutdown
                    ip=strcat('http://',ip_robot,':11311');
                    setenv('ROS_MASTER_URI',ip);
                    setenv('ROS_IP',ip_pc);
                    rosinit
                    
                    %% ENLACE A LOS TOPICOS DE ROS NECESARIOS
                    robot = rospublisher(cmd_r);
                    velmsg = rosmessage(robot);
                    odom = rossubscriber(odom_r);
                    lidarSub = rossubscriber(laser_r);
                    
                    conexion_bandera=1;
                    desconexion_bandera=1;
                    mensaje_inicio='El Robot se conecto exitosamente';
                    msgbox(mensaje_inicio,'Aviso Robot');
                    disp("El Robot se conecto exitosamente");
                    
                elseif (ejecucion_bandera_proporcional==1)||(ejecucion_bandera_fuzzy==1)||(ejecucion_bandera_optimo==1)
                    mensaje_inicio='Accion no permitida';
                    msgbox(mensaje_inicio,'Aviso Robot');
                    disp("Accion no permitida");
                    conexion_bandera=1;
                    desconexion_bandera=1;
                else
                    mensaje_inicio='No se coloco correctamente un topico';
                    msgbox(mensaje_inicio,'Aviso Robot');
                    disp("No se coloco correctamente un topico");
                    
                end
            end
             
        end

        % Button pushed function: Desconexion_robot
        function Desconexion_robotButtonPushed(app, event)
            global conexion_bandera desconexion_bandera robot velmsg;
            
            global ejecucion_bandera_proporcional ejecucion_bandera_fuzzy ejecucion_bandera_optimo;
            
            if(desconexion_bandera==1)&&(ejecucion_bandera_proporcional==0)&&(ejecucion_bandera_fuzzy==0)&&(ejecucion_bandera_optimo==0)
                
                desconexion_bandera=0;
                
                conexion_bandera=0;
                
                velmsg.Linear.X = 0;
                
                velmsg.Angular.Z = 0;
                
                send(robot,velmsg);
                
                rosshutdown;
                mensaje_inicio='Desconexion exitosa';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Desconexion exitosa");
                
            else
                mensaje_inicio='Accion no Permitida';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Accion no Permitida");
            end
        end

        % Value changed function: ip_Computador
        function ip_ComputadorValueChanged(app, event)
            global ip_pc
            value = app.ip_Computador.Value;
            ip_pc=value;
        end

        % Callback function
        function SlamButtonPushed(app, event)
     
        end

        % Value changed function: Laser_topic
        function Laser_topicValueChanged(app, event)
            global laser_r
            value = app.Laser_topic.Value;
            laser_r=value;
        end

        % Button pushed function: Detener_proporcional
        function Detener_proporcionalButtonPushed(app, event)
            global detener_bandera_proporcional ejecucion_bandera_proporcional
            if(ejecucion_bandera_proporcional==0)
                mensaje_inicio='Aun no se ejecuta el programa';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Aun no se ejecuta el programa");
            else
                detener_bandera_proporcional=1;
            end
            
        end

        % Button pushed function: Inicio_fuzzy
        function Inicio_fuzzyButtonPushed(app, event)
            %% variables propias del controlador
            global ip_robot ip_pc cmd_r odom_r laser_r conexion_bandera robot velmsg odom lidarSub PARAMETROS;
            global ejecucion_bandera_fuzzy detener_bandera_fuzzy;
            
            %% variables para no tener problemas de ejecucion con otros controladores
            global ejecucion_bandera_proporcional ejecucion_bandera_optimo;
            
            %% Variables auxiliares para los datos de trayectoria verificacion de que se colocaran
            auxiliar1=isempty(app.x_deseado_fuzzy.Value);
            
            auxiliar2=isempty(app.y_deseado_fuzzy.Value);
            
            %% variables auxiliares para los datos de comunicacion
            inicializacion_auxiliar1=isempty(ip_robot);
            
            inicializacion_auxiliar2=isempty(cmd_r);
            
            inicializacion_auxiliar3=isempty(odom_r);
            
            inicializacion_auxiliar4=isempty(ip_pc);
            
            inicializacion_auxiliar5=isempty(laser_r);
            
            %% Definicion de los tiempos de la simulacion
            
            ts=app.T_s_fuzzy.Value;
            
            tfinal=app.T_final_fuzzy.Value;
            
            t=[0:ts:tfinal];
            
            auxiliar3=ones(1,length(t));
            
            %% Condiciones para iniciar el programa de control
          
            if(auxiliar1==1)||(auxiliar2==1)
                mensaje_inicio='Colocar los parametros de trayectarias o tiempos';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de trayectorias o tiempos");
                
            elseif(inicializacion_auxiliar1==1)||(inicializacion_auxiliar2==1)||(inicializacion_auxiliar3==1)||(inicializacion_auxiliar4==1)||(inicializacion_auxiliar5==1) 
                
                mensaje_inicio='Colocar los parametros de comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de comunicacion");
            
            elseif (conexion_bandera==0)
                
                mensaje_inicio='El Robot no ha establecido Comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("El Robot no ha establecido Comunicacion");
                
            elseif (ejecucion_bandera_proporcional==1)||(ejecucion_bandera_optimo==1)||(ejecucion_bandera_fuzzy==1)
                mensaje_inicio='Accion no permitida en ejecucion otro controlador';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Accion no permitida en ejecucion otro controlador");   
                
            else
                %% LECTURA DEL ROBOT PARA CONDICIONES INICIALES
                odomdata = receive(odom,3);
                pose = odomdata.Pose.Pose;
                vel=odomdata.Twist.Twist;
                quat = pose.Orientation;
                angles = quat2eul([quat.W quat.X quat.Y quat.Z]);

                %% DISTANCIA HACIA EL PUNTO DE INTERES
                a=0.1;

                %% POSICIONES INICIALES 
                hx(1)=pose.Position.X;
                hy(1)=pose.Position.Y;
                phi(1)=angles(1);
                
                %% VELOCIDADES DE CONTROL INICIALES REALES
                u(1) = vel.Linear.X;
                w(1) = vel.Angular.Z;

                %% CINEMATICA DIRECTA 
                hx(1)=hx(1)+a*cos(phi(1));
                hy(1)=hy(1)+a*sin(phi(1));

                %% VELOCIDADES EN CADA EJE DEL ROBOT INICIALES
                hxp(1)=u(1)*cos(phi(1))-a*w(1)*sin(phi(1));
                hyp(1)=u(1)*sin(phi(1))+a*w(1)*cos(phi(1));
                
                
                %% TRAYECTORIA DESEADAS
                hxd=eval(app.x_deseado_fuzzy.Value).*auxiliar3;
                
                hyd=eval(app.y_deseado_fuzzy.Value).*auxiliar3;
                
                hxdp=[0 diff(hxd)/ts];
                
                hydp=[0 diff(hyd)/ts];
                
                %% LECTURA DE CONTROLADOR FUZZY
                self_x=readfis('fuzzpid');
                self_y=readfis('fuzzpid');

                %% VALOR INICIAL INTEGRAL DEL ERROR
                si=[0;0];

                %% GANANCIA PARA LOS ACTUADORES
                K2=1; 
                
                cla(app.Movimiento_fuzzy,"reset");
                
                cla(app.Errores_posicion_fuzzy,"reset");
                
                cla(app.Errores_velocidad_fuzzy,"reset");
                
                cla(app.Velocidades_reales_fuzzy,"reset");
                
                cla(app.Velocidades_dinamica_fuzzy,"reset");
                
                %% Definicion de las propiedades de la grafica Movimeinto
            
                title(app.Movimiento_fuzzy,'$\textrm{Trayectoria Deseada y Trayectoria Descrita}$','Interpreter','latex','FontSize',12);
                app.Movimiento_fuzzy.XMinorGrid='on';
                app.Movimiento_fuzzy.YMinorGrid='on';
                xlabel(app.Movimiento_fuzzy,'$\textrm{X}[m]$','Interpreter','latex','FontSize',9); 
                ylabel( app.Movimiento_fuzzy,'$\textrm{Y}[m]$','Interpreter','latex','FontSize',9);
                app.Movimiento_fuzzy.XLim=[-1 1];
                app.Movimiento_fuzzy.YLim=[-1 1];
                ph1=plot(app.Movimiento_fuzzy,nan,nan,'g',nan,nan,'b');
                
                
                %% Definicion de las propiedades de la grafica de los errores de posicion
                title(app.Errores_posicion_fuzzy,'$\textrm{Errores de Posicion}$','Interpreter','latex','FontSize',12);
                app.Errores_posicion_fuzzy.XMinorGrid='on';
                app.Errores_posicion_fuzzy.YMinorGrid='on';
                xlabel(app.Errores_posicion_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_posicion_fuzzy,'$[m]$','Interpreter','latex','FontSize',9);
                app.Errores_posicion_fuzzy.XLim=[0 t(end)];
                ph2=plot(app.Errores_posicion_fuzzy,nan,nan,'r',nan,nan,'g');
                
                
                %% Definicion de las propiedades de la grafica de los errores de velocidad
                title(app.Errores_velocidad_fuzzy,'$\textrm{Errores de Velocidad}$','Interpreter','latex','FontSize',12);
                app.Errores_velocidad_fuzzy.XMinorGrid='on';
                app.Errores_velocidad_fuzzy.YMinorGrid='on';
                xlabel(app.Errores_velocidad_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_velocidad_fuzzy,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Errores_velocidad_fuzzy.XLim=[0 t(end)];
                ph3=plot(app.Errores_velocidad_fuzzy,nan,nan,'r',nan,nan,'b');
                
                %% Definicion de las propiedades de la grafica de las velocidades de control y reales
                
                title(app.Velocidades_reales_fuzzy,'$\textrm{Velocidades a la Salida del Robot y Velocidades de Control Cinematico}$','Interpreter','latex','FontSize',12);
                app.Velocidades_reales_fuzzy.XMinorGrid='on';
                app.Velocidades_reales_fuzzy.YMinorGrid='on';
                xlabel(app.Velocidades_reales_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_reales_fuzzy,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_reales_fuzzy.XLim=[0 t(end)];
                ph4=plot(app.Velocidades_reales_fuzzy,nan,nan,'r',nan,nan,'b',nan,nan,'--r',nan,nan,'--b');
                
                %% Definicion de las propiedades de la grafica de la compensacion dinamica
                
                
                title(app.Velocidades_dinamica_fuzzy,'$\textrm{Velocidades de Compensacion Dinamica}$','Interpreter','latex','FontSize',12);
                app.Velocidades_dinamica_fuzzy.XMinorGrid='on';
                app.Velocidades_dinamica_fuzzy.YMinorGrid='on';
                xlabel(app.Velocidades_dinamica_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_dinamica_fuzzy,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_dinamica_fuzzy.XLim=[0 t(end)];
                ph5=plot(app.Velocidades_dinamica_fuzzy,nan,nan,'r',nan,nan,'g');
                
                %% Definicion de las propiedades de la grafica de ganancias adaptativas proporcionales
                title(app.Adaptacion_proporcional_fuzzy,'$\textrm{Adaptacion Ganancias Proporcionales}$','Interpreter','latex','FontSize',12);
                app.Adaptacion_proporcional_fuzzy.XMinorGrid='on';
                app.Adaptacion_proporcional_fuzzy.YMinorGrid='on';
                xlabel(app.Adaptacion_proporcional_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Adaptacion_proporcional_fuzzy,'$[K_{p}]$','Interpreter','latex','FontSize',9);
                app.Adaptacion_proporcional_fuzzy.XLim=[0 t(end)];
                ph6=plot(app.Adaptacion_proporcional_fuzzy,nan,nan,'r',nan,nan,'b');
                
                %% Definicion de las propiedades de la grafica de ganancias adaptativas derivativas
                title(app.Adaptacion_derivativo_fuzzy,'$\textrm{Adaptacion Ganancias Derivativas}$','Interpreter','latex','FontSize',12);
                app.Adaptacion_derivativo_fuzzy.XMinorGrid='on';
                app.Adaptacion_derivativo_fuzzy.YMinorGrid='on';
                xlabel(app.Adaptacion_derivativo_fuzzy,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Adaptacion_derivativo_fuzzy,'$[K_{d}]$','Interpreter','latex','FontSize',9);
                app.Adaptacion_derivativo_fuzzy.XLim=[0 t(end)];
                ph7=plot(app.Adaptacion_derivativo_fuzzy,nan,nan,'r',nan,nan,'b');
                
                xdata=[];
                ydata=[];
                
                xd_data=[];
                yd_data=[];
                
                
%                 world=World(app,lidarSub,[hx(1),hy(1),phi(1)]);
                
                for k=1:length(t)
                    %% Auxiliar para no poder desconcectar durante la ejecucion
                    ejecucion_bandera_fuzzy=1;
                    if(detener_bandera_fuzzy==1)
                        mensaje_inicio='El Robot se ha detenido';
                        msgbox(mensaje_inicio,'Aviso Robot');
                        disp("El Robot se ha Desconectado");
                       break; 
                    end
                    
                    %% Parte para iluminar led
                    app.Estado_fuzzy.Color = [0 1 0];
                    
                    %% Parte para obtener el timepo de muestreo
                    tic;
                    
                    %% auxiliar para las graficas si surgue un problema
                    t_auxiliar(k)=t(k);
                    
                    %% ERROR DE CONTROL
                    hxe(k)=hxd(k)-hx(k);
    
                    hye(k)=hyd(k)-hy(k);
    
                    %% VECTORES PARA FORMA GENERAL
                    hd=[hxd(k) hyd(k)]';
    
                    hdp=[hxdp(k) hydp(k)]';
    
                    h =[hx(k),hy(k)]';
    
                    hp=[hxp(k),hyp(k)]';
                    
                    he=hd-h;
    
                    q=[0 phi(k)]'; 
                    
                    %% CALCULO DEL ERROR DE MODELADO
                    si=si+he*ts;
    
                     %% CALCULPO DE LAS GANANCIAS
                    [KP(:,k),KD(:,k)]=FUZZY_ADAPTATIVO(app,self_x,self_y,hd,hdp,h,hp);
    
                    %% CONTROLADOR PROPORCIONAL DERIVATIVO
                    qref=PROPORCIONAL_DERIVATIVO(app,KP(:,k),KD(:,k),K2,hd,hdp,h,hp,q,a,si);
                    
                    %% VELOCIDADES CINEMATICAS O VELOCIDADES DESEADAS PARA EL BLOQUE DE COMPENSACIÓN DIN�?MICA
                    uref_c(k)=qref(1);
                    wref_c(k)=qref(2);
    
                    %% ERRORES DE VELOCIDAD VREF
                    ue(k)=uref_c(k)-u(k);
                    we(k)=wref_c(k)-w(k);
                    vref_e=[ue(k) we(k)]';
    
                    %% DERIVADAS DE LAS VREF DESEADAS
                    vrefp_u=diff([uref_c uref_c(end)])/ts;
                    vrefp_w=diff([wref_c wref_c(end)])/ts;
                    vrefp=[vrefp_u(k) vrefp_w(k)]';
                    v=[uref_c(k) wref_c(k)]';
    
                    %% COMPENSACION DINAMICA PLATAFORMA MOVIL
                    Dinamica = COMPENSACION_DINAMICA_PLATAFORMA_MOVIL_N(app,vrefp,vref_e,v,q,ts,PARAMETROS);
    
                    %% DINAMICA DE LA PLATAFORMA MOVIL
                    uref(k)=Dinamica(1);
                    wref(k)=Dinamica(2);

                    %% ENVIO DE DATOS AL ROBOT
                    velmsg.Linear.X = uref(k);
                    velmsg.Angular.Z =wref(k);
                    send(robot,velmsg);
    
                    %% LECTURA DE LAS POSICIONES DEL ROBOT
                    odomdata = receive(odom,3);
                    pose = odomdata.Pose.Pose;
                    quat = pose.Orientation;
                    angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
    
                    %% LECTURA DE LAS VELOCIDADES DE CONTROL REALES DEL ROBOT
                    vel=odomdata.Twist.Twist;
    
                    %% VELOCIDADES DEL ROBOT
                    u(k+1)=vel.Linear.X;
                    aux=vel.Angular.Z;
                    w(k+1)=Filtro(app,vel.Angular.Z,w(k));
   
                    %% CINEMATICA DIRECTA
                    phi(k+1)=angles(1);
     
                    hxp(k+1)=u(k+1)*cos(phi(k+1))-a*w(k+1)*sin(phi(k+1));
                    hyp(k+1)=u(k+1)*sin(phi(k+1))+a*w(k+1)*cos(phi(k+1));
     
                    hx(k+1)=pose.Position.X+a*cos(phi(k+1));
                    hy(k+1)=pose.Position.Y+a*sin(phi(k+1));
                    
%                     world=World(app,lidarSub,[hx(k+1),hy(k+1),phi(k+1)]);
                    
                    %% GRAFICAS SISTEMA
                    xdata=[xdata hx(k)];
                
                    ydata=[ydata hy(k)];
                    
                    xd_data=[xd_data hxd(k)];
                    
                    yd_data=[yd_data hyd(k)];


%                     xworld=[world(:,1)]';
%                     yworld=[world(:,2)]';
                    
                    %% GRAFICAS DE LA POSICION
                    set(ph1(1),'XData',xd_data,'YData',yd_data);
                    set(ph1(2),'XData',xdata,'YData',ydata);
%                     set(ph1(3),'XData',xworld,'YData',yworld);
                    legend(app.Movimiento_fuzzy,{'$\mathbf{\eta}_{p_{des}}$','$\mathbf{\eta}_{p}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                    legend(app.Movimiento_fuzzy,'boxoff')
                    
                   
                    drawnow;
                    while(toc<ts)
                    end
                    toc
                    t_sample(k)=toc;
                end
                %% MENSAJE PARA IR A RESULTADOS
                mensaje_inicio='Ir a resultados';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Ir a resultados");
                %% GRAFICAS DE LOS ERRORES DE POSICION
                set(ph2(1),'XData',t_auxiliar,'YData',hxe(1:length(t_auxiliar)));
                set(ph2(2),'XData',t_auxiliar,'YData',hye(1:length(t_auxiliar)));
                legend(app.Errores_posicion_fuzzy,{'$\tilde{x_{p}}$','$\tilde{y_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_posicion_fuzzy,'boxoff');
                
                %% GRAFICAS DE LOS ERRORES DE VELOCIDAD
                set(ph3(1),'XData',t_auxiliar,'YData',ue(1:length(t_auxiliar)));
                set(ph3(2),'XData',t_auxiliar,'YData',we(1:length(t_auxiliar)));
                legend(app.Errores_velocidad_fuzzy,{'$\tilde\mu$','$\tilde{\dot\psi_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_velocidad_fuzzy,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES REALES Y LA DEL CONTROLADOR
                set(ph4(1),'XData',t_auxiliar,'YData',u(1:length(t_auxiliar)));
                set(ph4(2),'XData',t_auxiliar,'YData',w(1:length(t_auxiliar)));
                set(ph4(3),'XData',t_auxiliar,'YData',uref_c(1:length(t_auxiliar)));
                set(ph4(4),'XData',t_auxiliar,'YData',wref_c(1:length(t_auxiliar)));
                legend(app.Velocidades_reales_fuzzy,{'$\mu$','$\dot\psi_{p}$','$\mu_{ref_{c}}$','$\dot\psi_{p_{ref_{c}}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_reales_fuzzy,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES DE COMPENSACION DINAMICA
                set(ph5(1),'XData',t_auxiliar,'YData',uref(1:length(t_auxiliar)));
                set(ph5(2),'XData',t_auxiliar,'YData',wref(1:length(t_auxiliar)));
                legend(app.Velocidades_dinamica_fuzzy,{'$\mu_{ref}$','$\dot\psi_{p_{ref}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_dinamica_fuzzy,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES DE COMPENSACION DINAMICA
                set(ph6(1),'XData',t_auxiliar,'YData',KP(1,1:length(t_auxiliar)));
                set(ph6(2),'XData',t_auxiliar,'YData',KP(2,1:length(t_auxiliar)));
                legend(app.Adaptacion_proporcional_fuzzy,{'$K_{p_{x}}$','$K_{p_{y}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Adaptacion_proporcional_fuzzy,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES DE COMPENSACION DINAMICA
                set(ph7(1),'XData',t_auxiliar,'YData',KD(1,1:length(t_auxiliar)));
                set(ph7(2),'XData',t_auxiliar,'YData',KD(2,1:length(t_auxiliar)));
                legend(app.Adaptacion_derivativo_fuzzy,{'$K_{d_{x}}$','$K_{d_{y}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Adaptacion_derivativo_fuzzy,'boxoff');
                
                %% Desactivar led al acabar el programa
                app.Estado_fuzzy.Color = [0.149 0.149 0.149];
                
                velmsg.Linear.X = 0;
                velmsg.Angular.Z = 0;
                send(robot,velmsg);
                
                ejecucion_bandera_fuzzy=0;
                detener_bandera_fuzzy=0;
            end  
        end

        % Button pushed function: Detener_fuzzy
        function Detener_fuzzyButtonPushed(app, event)
            global detener_bandera_fuzzy ejecucion_bandera_fuzzy
            if(ejecucion_bandera_fuzzy==0)
                mensaje_inicio='Aun no se ejecuta el programa';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Aun no se ejecuta el programa");
            else
                detener_bandera_fuzzy=1;
            end
        end

        % Button pushed function: Detener_optimo
        function Detener_optimoButtonPushed(app, event)
            global detener_bandera_optimo ejecucion_bandera_optimo
            if(ejecucion_bandera_optimo==0)
                mensaje_inicio='Aun no se ejecuta el programa';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Aun no se ejecuta el programa");
            else
                detener_bandera_optimo=1;
            end
        end

        % Button pushed function: Inicio_optimo
        function Inicio_optimoButtonPushed(app, event)
            %% variables globales propias del controlador
            global ip_robot ip_pc cmd_r odom_r laser_r conexion_bandera robot velmsg odom lidarSub PARAMETROS;
            
            global ejecucion_bandera_optimo detener_bandera_optimo;
            
            %% variables globales para evitar confunsion con los demas controladores
            global ejecucion_bandera_fuzzy ejecucion_bandera_proporcional;
            
            %% Variables auxiliares para los datos de trayectoria verificacion de que se colocaran
            auxiliar1=isempty(app.x_deseado_optimo.Value);
            
            auxiliar2=isempty(app.y_deseado_optimo.Value);
            
            %% variables auxiliares para los datos de comunicacion
            inicializacion_auxiliar1=isempty(ip_robot);
            
            inicializacion_auxiliar2=isempty(cmd_r);
            
            inicializacion_auxiliar3=isempty(odom_r);
            
            inicializacion_auxiliar4=isempty(ip_pc);
            
            inicializacion_auxiliar5=isempty(laser_r);
            
            %% Definicion de los tiempos de la simulacion
            
            ts=app.T_s_optimo.Value;
            
            tfinal=app.T_final_optimo.Value;
            
            t=[0:ts:tfinal];
            
            auxiliar3=ones(1,length(t));
            
            %% Condiciones para iniciar el programa de control
          
            if(auxiliar1==1)||(auxiliar2==1)
                mensaje_inicio='Colocar los parametros de trayectarias o tiempos';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de trayectorias o tiempos");
                
            elseif(inicializacion_auxiliar1==1)||(inicializacion_auxiliar2==1)||(inicializacion_auxiliar3==1)||(inicializacion_auxiliar4==1)||(inicializacion_auxiliar5==1) 
                
                mensaje_inicio='Colocar los parametros de comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Colocar los parametros de comunicacion");
                
            elseif (conexion_bandera==0)
                
                mensaje_inicio='El Robot no ha establecido Comunicacion';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("El Robot no ha establecido Comunicacion");
                
            elseif (ejecucion_bandera_fuzzy==1)||(ejecucion_bandera_proporcional==1)||(ejecucion_bandera_optimo==1)
                mensaje_inicio='Accion no permitida en ejecucion otro controlador';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Accion no permitida en ejecucion otro controlador");
            else
                %% LECTURA DEL ROBOT PARA CONDICIONES INICIALES
                odomdata = receive(odom,3);
                pose = odomdata.Pose.Pose;
                vel=odomdata.Twist.Twist;
                quat = pose.Orientation;
                angles = quat2eul([quat.W quat.X quat.Y quat.Z]);

                %% DISTANCIA HACIA EL PUNTO DE INTERES
                a=0.1;
                l=[a]';
                %% POSICIONES INICIALES 
                hx(1)=pose.Position.X;
                hy(1)=pose.Position.Y;
                phi(1)=angles(1);
                
                %% VELOCIDADES DE CONTROL INICIALES REALES
                u(1) = vel.Linear.X;
                w(1) = vel.Angular.Z;

                %% CINEMATICA DIRECTA 
                hx(1)=hx(1)+a*cos(phi(1));
                hy(1)=hy(1)+a*sin(phi(1));

                %% VELOCIDADES EN CADA EJE DEL ROBOT INICIALES
                hxp(1)=u(1)*cos(phi(1))-a*w(1)*sin(phi(1));
                hyp(1)=u(1)*sin(phi(1))+a*w(1)*cos(phi(1));
                
                
                %% TRAYECTORIA DESEADAS
                hxd=eval(app.x_deseado_optimo.Value).*auxiliar3;
                
                hyd=eval(app.y_deseado_optimo.Value).*auxiliar3;
                
                hxdp=[0 diff(hxd)/ts];
                
                hydp=[0 diff(hyd)/ts];
                
                %% RESTRICCION PARA LAS ACCIONES DE CONTROL
                lb = [-0.2,-2.55]';
                ub = [ 0.2, 2.55]';
                z0=[u(1),w(1)]';
                
                %% CONFIGURACION DEL METODO DE OPTIMIZACION A UTILIZAR
                options = optimset('Algorithm','sqp','Display','off');
                
                %% Eliminar las graficas para nuevo plot
                cla(app.Movimiento_optimo,"reset");
                
                cla(app.Errores_posicion_optimo,"reset");
                
                cla(app.Errores_velocidad_optimo,"reset");
                
                cla(app.Velocidades_reales_optimo,"reset");
                
                cla(app.Velocidades_dinamica_optimo,"reset");
                
                %% Definicion de las propiedades de la grafica Movimeinto
            
                title(app.Movimiento_optimo,'$\textrm{Trayectoria Deseada y Trayectoria Descrita}$','Interpreter','latex','FontSize',12);
                app.Movimiento_optimo.XMinorGrid='on';
                app.Movimiento_optimo.YMinorGrid='on';
                xlabel(app.Movimiento_optimo,'$\textrm{X}[m]$','Interpreter','latex','FontSize',9); 
                ylabel( app.Movimiento_optimo,'$\textrm{Y}[m]$','Interpreter','latex','FontSize',9);
                app.Movimiento_optimo.XLim=[-1 1];
                app.Movimiento_optimo.YLim=[-1 1];
                ph1=plot(app.Movimiento_optimo,nan,nan,'g',nan,nan,'b');
                
                
                %% Definicion de las propiedades de la grafica de los errores de posicion
                title(app.Errores_posicion_optimo,'$\textrm{Errores de Posicion}$','Interpreter','latex','FontSize',12);
                app.Errores_posicion_optimo.XMinorGrid='on';
                app.Errores_posicion_optimo.YMinorGrid='on';
                xlabel(app.Errores_posicion_optimo,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_posicion_optimo,'$[m]$','Interpreter','latex','FontSize',9);
                app.Errores_posicion_optimo.XLim=[0 t(end)];
                ph2=plot(app.Errores_posicion_optimo,nan,nan,'r',nan,nan,'g');
                
                
                %% Definicion de las propiedades de la grafica de los errores de velocidad
                title(app.Errores_velocidad_optimo,'$\textrm{Errores de Velocidad}$','Interpreter','latex','FontSize',12);
                app.Errores_velocidad_optimo.XMinorGrid='on';
                app.Errores_velocidad_optimo.YMinorGrid='on';
                xlabel(app.Errores_velocidad_optimo,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Errores_velocidad_optimo,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Errores_velocidad_optimo.XLim=[0 t(end)];
                ph3=plot(app.Errores_velocidad_optimo,nan,nan,'r',nan,nan,'b');
                
                %% Definicion de las propiedades de la grafica de las velocidades de control y reales
                
                title(app.Velocidades_reales_optimo,'$\textrm{Velocidades a la Salida del Robot y Velocidades de Control Cinematico}$','Interpreter','latex','FontSize',12);
                app.Velocidades_reales_optimo.XMinorGrid='on';
                app.Velocidades_reales_optimo.YMinorGrid='on';
                xlabel(app.Velocidades_reales_optimo,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_reales_optimo,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_reales_optimo.XLim=[0 t(end)];
                ph4=plot(app.Velocidades_reales_optimo,nan,nan,'r',nan,nan,'b',nan,nan,'--r',nan,nan,'--b');
                
                %% Definicion de las propiedades de la grafica de la compensacion dinamica
                
                
                title(app.Velocidades_dinamica_optimo,'$\textrm{Velocidades de Compensacion Dinamica}$','Interpreter','latex','FontSize',12);
                app.Velocidades_dinamica_optimo.XMinorGrid='on';
                app.Velocidades_dinamica_optimo.YMinorGrid='on';
                xlabel(app.Velocidades_dinamica_optimo,'$\textrm{Tiempo}[s]$','Interpreter','latex','FontSize',9);
                ylabel(app.Velocidades_dinamica_optimo,'$[m/s][rad/s]$','Interpreter','latex','FontSize',9);
                app.Velocidades_dinamica_optimo.XLim=[0 t(end)];
                ph5=plot(app.Velocidades_dinamica_optimo,nan,nan,'r',nan,nan,'g');


                xdata=[];
                ydata=[];
                
                xd_data=[];
                yd_data=[];
                
                
%                 world=World(app,lidarSub,[hx(1),hy(1),phi(1)]);
                
                for k=1:length(t)-1
                    %% Auxiliar para no poder desconcectar durante la ejecucion
                    ejecucion_bandera_optimo=1;
                    if(detener_bandera_optimo==1)
                        mensaje_inicio='El Robot se ha detenido';
                        msgbox(mensaje_inicio,'Aviso Robot');
                        disp("El Robot se ha Desconectado");
                       break; 
                    end
                    
                    %% Parte para iluminar led
                    app.Estado_optimo.Color = [0 1 0];
                    
                    %% Parte para obtener el timepo de muestreo
                    tic;
                    
                    %% auxiliar para las graficas si surgue un problema
                    t_auxiliar(k)=t(k);
                    
                    %% ERROR DE CONTROL
                    hxe(k)=hxd(k)-hx(k);
    
                    hye(k)=hyd(k)-hy(k);
    
                    %% VECTORES PARA FORMA GENERAL
                    hd=[hxd(k) hyd(k)]';
                    
                    hd1=[hxd(k+1) hyd(k+1)]';
                    
                    h =[hx(k),hy(k)]';
                    
                    hp=[hxp(k),hyp(k)]';
                    
                    q=[0 phi(k)]'; 
                    
                    v=[u(k),w(k)]';
                    %% GANANCIA PARA OPTIMIZADOR
                    H=[1,0;...
                        0,1];
    
                    %% CONTROLADOR BASADO EN OPTIMIZACION
                    f_obj = @(z) movil_optimo(app,z,H,hd,hd1,h,q,l,ts);
                     
                    qref = fmincon(f_obj,z0,[],[],[],[],lb,ub,[],options);
     
                    %% VELOCIDADES CINEMATICAS O VELOCIDADES DESEADAS PARA EL BLOQUE DE COMPENSACIÓN DIN�?MICA
                    uref_c(k)=qref(1);
                    wref_c(k)=qref(2);
    
                    %% ERRORES DE VELOCIDAD VREF
                    ue(k)=uref_c(k)-u(k);
                    we(k)=wref_c(k)-w(k);
                    vref_e=[ue(k) we(k)]';
    
                    %% DERIVADAS DE LAS VREF DESEADAS
                    vrefp_u=diff([uref_c uref_c(end)])/ts;
                    vrefp_w=diff([wref_c wref_c(end)])/ts;
                    vrefp=[vrefp_u(k) vrefp_w(k)]';
                    v=[uref_c(k) wref_c(k)]';
    
                    %% COMPENSACION DINAMICA PLATAFORMA MOVIL
                    Dinamica = COMPENSACION_DINAMICA_PLATAFORMA_MOVIL_N(app,vrefp,vref_e,v,q,ts,PARAMETROS);
    
                    %% DINAMICA DE LA PLATAFORMA MOVIL
                    uref(k)=Dinamica(1);
                    wref(k)=Dinamica(2);

                    %% ENVIO DE DATOS AL ROBOT
                    velmsg.Linear.X = uref(k);
                    velmsg.Angular.Z =wref(k);
                    send(robot,velmsg);
    
                    %% LECTURA DE LAS POSICIONES DEL ROBOT
                    odomdata = receive(odom,3);
                    pose = odomdata.Pose.Pose;
                    quat = pose.Orientation;
                    angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
    
                    %% LECTURA DE LAS VELOCIDADES DE CONTROL REALES DEL ROBOT
                    vel=odomdata.Twist.Twist;
    
                    %% VELOCIDADES DEL ROBOT
                    u(k+1)=vel.Linear.X;
                    aux=vel.Angular.Z;
                    w(k+1)=Filtro(app,vel.Angular.Z,w(k));
   
                    %% CINEMATICA DIRECTA
                    phi(k+1)=angles(1);
     
                    hxp(k+1)=u(k+1)*cos(phi(k+1))-a*w(k+1)*sin(phi(k+1));
                    hyp(k+1)=u(k+1)*sin(phi(k+1))+a*w(k+1)*cos(phi(k+1));
     
                    hx(k+1)=pose.Position.X+a*cos(phi(k+1));
                    hy(k+1)=pose.Position.Y+a*sin(phi(k+1));
                    
                    %% ayuda al optimizador para encontrar soluciones mas rapido
                    z0 = [u(k+1),w(k+1)]';
%                     world=World(app,lidarSub,[hx(k+1),hy(k+1),phi(k+1)]);
                    
                    %% GRAFICAS SISTEMA
                    xdata=[xdata hx(k)];
                
                    ydata=[ydata hy(k)];
                    
                    xd_data=[xd_data hxd(k)];
                    
                    yd_data=[yd_data hyd(k)];


%                     xworld=[world(:,1)]';
%                     yworld=[world(:,2)]';
                    
                    %% GRAFICAS DE LA POSICION
                    set(ph1(1),'XData',xd_data,'YData',yd_data);
                    set(ph1(2),'XData',xdata,'YData',ydata);
%                     set(ph1(3),'XData',xworld,'YData',yworld);
                    legend(app.Movimiento_optimo,{'$\mathbf{\eta}_{p_{des}}$','$\mathbf{\eta}_{p}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                    legend(app.Movimiento_optimo,'boxoff')
                    
                   
                    drawnow;
                    while(toc<ts)
                    end
                    toc
                    t_sample(k)=toc;
                end
                %% MENSAJE PARA IR A RESULTADOS
                mensaje_inicio='Ir a resultados';
                msgbox(mensaje_inicio,'Aviso Robot');
                disp("Ir a resultados");
                %% GRAFICAS DE LOS ERRORES DE POSICION
                set(ph2(1),'XData',t_auxiliar,'YData',hxe(1:length(t_auxiliar)));
                set(ph2(2),'XData',t_auxiliar,'YData',hye(1:length(t_auxiliar)));
                legend(app.Errores_posicion_optimo,{'$\tilde{x_{p}}$','$\tilde{y_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_posicion_optimo,'boxoff');
                
                %% GRAFICAS DE LOS ERRORES DE VELOCIDAD
                set(ph3(1),'XData',t_auxiliar,'YData',ue(1:length(t_auxiliar)));
                set(ph3(2),'XData',t_auxiliar,'YData',we(1:length(t_auxiliar)));
                legend(app.Errores_velocidad_optimo,{'$\tilde\mu$','$\tilde{\dot\psi_{p}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Errores_velocidad_optimo,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES REALES Y LA DEL CONTROLADOR
                set(ph4(1),'XData',t_auxiliar,'YData',u(1:length(t_auxiliar)));
                set(ph4(2),'XData',t_auxiliar,'YData',w(1:length(t_auxiliar)));
                set(ph4(3),'XData',t_auxiliar,'YData',uref_c(1:length(t_auxiliar)));
                set(ph4(4),'XData',t_auxiliar,'YData',wref_c(1:length(t_auxiliar)));
                legend(app.Velocidades_reales_optimo,{'$\mu$','$\dot\psi_{p}$','$\mu_{ref_{c}}$','$\dot\psi_{p_{ref_{c}}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_reales_optimo,'boxoff');
                
                %% GRAFICAS DE LAS VELOCIDADES DE COMPENSACION DINAMICA
                set(ph5(1),'XData',t_auxiliar,'YData',uref(1:length(t_auxiliar)));
                set(ph5(2),'XData',t_auxiliar,'YData',wref(1:length(t_auxiliar)));
                legend(app.Velocidades_dinamica_optimo,{'$\mu_{ref}$','$\dot\psi_{p_{ref}}$'},'Interpreter','latex','FontSize',11,'Orientation','horizontal');
                legend(app.Velocidades_dinamica_optimo,'boxoff');
                
                %% Desactivar led al acabar el programa
                app.Estado_optimo.Color = [0.149 0.149 0.149];
                
                velmsg.Linear.X = 0;
                velmsg.Angular.Z = 0;
                send(robot,velmsg);
                
                ejecucion_bandera_optimo=0;
                detener_bandera_optimo=0;
            end  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComunicacionTab = uitab(app.TabGroup);
            app.ComunicacionTab.Title = 'Comunicacion';
            app.TabGroup3 = uitabgroup(app.ComunicacionTab);
            app.ConexionTab = uitab(app.TabGroup3);
            app.ConexionTab.Title = 'Conexion';
            app.UNIVERSIDADDELASFUERZASARMADASESPELabel_2 = uilabel(app.ConexionTab);
            app.UNIVERSIDADDELASFUERZASARMADASESPELabel_2.Text = 'UNIVERSIDAD DE LAS FUERZAS ARMADAS ESPE';
            app.DireccionIPRobotLabel = uilabel(app.ConexionTab);
            app.DireccionIPRobotLabel.Text = 'Direccion IP Robot';
            app.ip_Robot = uieditfield(app.ConexionTab, 'text');
            app.ip_Robot.ValueChangedFcn = createCallbackFcn(app, @ip_RobotValueChanged, true);
            app.VelocidadTopicEditFieldLabel = uilabel(app.ConexionTab);
            app.VelocidadTopicEditFieldLabel.Text = 'Velocidad Topic';
            app.Velociad_topic = uieditfield(app.ConexionTab, 'text');
            app.Velociad_topic.ValueChangedFcn = createCallbackFcn(app, @Velociad_topicValueChanged, true);
            app.OdometriaTopicEditFieldLabel = uilabel(app.ConexionTab);
            app.OdometriaTopicEditFieldLabel.Text = 'Odometria Topic';
            app.Odometria_topic = uieditfield(app.ConexionTab, 'text');
            app.Odometria_topic.ValueChangedFcn = createCallbackFcn(app, @Odometria_topicValueChanged, true);
            app.Conexion_robot = uibutton(app.ConexionTab, 'push');
            app.Conexion_robot.ButtonPushedFcn = createCallbackFcn(app, @Conexion_robotButtonPushed, true);
            app.Conexion_robot.Text = 'Conectar';
            app.Desconexion_robot = uibutton(app.ConexionTab, 'push');
            app.Desconexion_robot.ButtonPushedFcn = createCallbackFcn(app, @Desconexion_robotButtonPushed, true);
            app.Desconexion_robot.Text = 'Desconectar';
            app.DireccionIPComputadorLabel = uilabel(app.ConexionTab);
            app.DireccionIPComputadorLabel.Text = 'Direccion IP Computador';
            app.ip_Computador = uieditfield(app.ConexionTab, 'text');
            app.ip_Computador.ValueChangedFcn = createCallbackFcn(app, @ip_ComputadorValueChanged, true);
            app.LaserTopicEditFieldLabel = uilabel(app.ConexionTab);
            app.LaserTopicEditFieldLabel.Text = 'Laser Topic';
            app.Laser_topic = uieditfield(app.ConexionTab, 'text');
            app.Laser_topic.ValueChangedFcn = createCallbackFcn(app, @Laser_topicValueChanged, true);
            app.ProporcionalTab = uitab(app.TabGroup);
            app.ProporcionalTab.Title = 'Proporcional';
            app.Proporcional = uitabgroup(app.ProporcionalTab);
            app.Ejecucion_proporcional = uitab(app.Proporcional);
            app.Ejecucion_proporcional.Title = 'Ejecucion';
            app.ControlProporcionalCompensacionDinamicaLabel = uilabel(app.Ejecucion_proporcional);
            app.ControlProporcionalCompensacionDinamicaLabel.Text = 'Control Proporcional + Compensacion Dinamica';
            app.Movimiento_proporcional = uiaxes(app.Ejecucion_proporcional);
            title(app.Movimiento_proporcional, 'Trayectoria Deseada y Trayectoria Descrita')
            xlabel(app.Movimiento_proporcional, 'X')
            ylabel(app.Movimiento_proporcional, 'Y')
            app.T_s_proporcional = uieditfield(app.Ejecucion_proporcional, 'numeric');
            app.T_s_proporcional.Value = 0.1;
            app.SampleTimeLabel = uilabel(app.Ejecucion_proporcional);
            app.SampleTimeLabel.Text = 'Sample Time';
            app.Inicio_proporcional = uibutton(app.Ejecucion_proporcional, 'push');
            app.Inicio_proporcional.ButtonPushedFcn = createCallbackFcn(app, @Inicio_proporcionalButtonPushed, true);
            app.Inicio_proporcional.Text = 'Iniciar';
            app.FinalTimeEditFieldLabel = uilabel(app.Ejecucion_proporcional);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.T_final_proporcional = uieditfield(app.Ejecucion_proporcional, 'numeric');
            app.DeseadoenXLabel = uilabel(app.Ejecucion_proporcional);
            app.DeseadoenXLabel.Text = 'Deseado en X';
            app.x_deseado_proporcional = uieditfield(app.Ejecucion_proporcional, 'text');
            app.x_deseado_proporcional.ValueChangedFcn = createCallbackFcn(app, @x_deseado_proporcionalValueChanged, true);
            app.DeseadoenYLabel = uilabel(app.Ejecucion_proporcional);
            app.DeseadoenYLabel.Text = 'Deseado en Y';
            app.y_deseado_proporcional = uieditfield(app.Ejecucion_proporcional, 'text');
            app.y_deseado_proporcional.ValueChangedFcn = createCallbackFcn(app, @y_deseado_proporcionalValueChanged, true);
            app.EstadoLabel = uilabel(app.Ejecucion_proporcional);
            app.EstadoLabel.Text = 'Estado';
            app.Estado_proporcional = uilamp(app.Ejecucion_proporcional);
            app.Detener_proporcional = uibutton(app.Ejecucion_proporcional, 'push');
            app.Detener_proporcional.ButtonPushedFcn = createCallbackFcn(app, @Detener_proporcionalButtonPushed, true);
            app.Detener_proporcional.Text = 'Detener';
            app.Resultados_proporcional = uitab(app.Proporcional);
            app.Resultados_proporcional.Title = 'Resultados';
            app.Errores_posicion_proporcional = uiaxes(app.Resultados_proporcional);
            title(app.Errores_posicion_proporcional, 'Errores de Posicion')
            xlabel(app.Errores_posicion_proporcional, 'Tiempo')
            ylabel(app.Errores_posicion_proporcional, 'Errores')
            app.Errores_velocidad_proporcional = uiaxes(app.Resultados_proporcional);
            title(app.Errores_velocidad_proporcional, 'Errores de Velocidad')
            xlabel(app.Errores_velocidad_proporcional, 'Tiempo')
            ylabel(app.Errores_velocidad_proporcional, 'Errores')
            app.Velocidades_reales_proporcional = uiaxes(app.Resultados_proporcional);
            title(app.Velocidades_reales_proporcional, 'Velocidades reales y Control')
            xlabel(app.Velocidades_reales_proporcional, 'Tiempo')
            app.Velocidades_dinamica_proporcional = uiaxes(app.Resultados_proporcional);
            title(app.Velocidades_dinamica_proporcional, 'Velocidades Compensacion DInamica')
            xlabel(app.Velocidades_dinamica_proporcional, 'Tiempo')
            app.FuzzyTab = uitab(app.TabGroup);
            app.FuzzyTab.Title = 'Fuzzy';
            app.Fuzzy = uitabgroup(app.FuzzyTab);
            app.Ejecucion_fuzzy = uitab(app.Fuzzy);
            app.Ejecucion_fuzzy.Title = 'Ejecucion';
            app.ControlFuzzyAdaptativoCompensacionDinamicaLabel = uilabel(app.Ejecucion_fuzzy);
            app.ControlFuzzyAdaptativoCompensacionDinamicaLabel.Text = 'Control Fuzzy Adaptativo + Compensacion Dinamica';
            app.Movimiento_fuzzy = uiaxes(app.Ejecucion_fuzzy);
            title(app.Movimiento_fuzzy, 'Trayectoria Deseada y Trayectoria Descrita')
            xlabel(app.Movimiento_fuzzy, 'X')
            ylabel(app.Movimiento_fuzzy, 'Y')
            app.T_s_fuzzy = uieditfield(app.Ejecucion_fuzzy, 'numeric');
            app.T_s_fuzzy.Value = 0.1;
            app.SampleTimeLabel_2 = uilabel(app.Ejecucion_fuzzy);
            app.SampleTimeLabel_2.Text = 'Sample Time';
            app.Inicio_fuzzy = uibutton(app.Ejecucion_fuzzy, 'push');
            app.Inicio_fuzzy.ButtonPushedFcn = createCallbackFcn(app, @Inicio_fuzzyButtonPushed, true);
            app.Inicio_fuzzy.Text = 'Iniciar';
            app.FinalTimeEditFieldLabel_2 = uilabel(app.Ejecucion_fuzzy);
            app.FinalTimeEditFieldLabel_2.Text = 'Final Time';
            app.T_final_fuzzy = uieditfield(app.Ejecucion_fuzzy, 'numeric');
            app.DeseadoenXLabel_2 = uilabel(app.Ejecucion_fuzzy);
            app.DeseadoenXLabel_2.Text = 'Deseado en X';
            app.x_deseado_fuzzy = uieditfield(app.Ejecucion_fuzzy, 'text');
            app.DeseadoenYLabel_2 = uilabel(app.Ejecucion_fuzzy);
            app.DeseadoenYLabel_2.Text = 'Deseado en Y';
            app.y_deseado_fuzzy = uieditfield(app.Ejecucion_fuzzy, 'text');
            app.EstadoLabel_2 = uilabel(app.Ejecucion_fuzzy);
            app.EstadoLabel_2.Text = 'Estado';
            app.Estado_fuzzy = uilamp(app.Ejecucion_fuzzy);
            app.Detener_fuzzy = uibutton(app.Ejecucion_fuzzy, 'push');
            app.Detener_fuzzy.ButtonPushedFcn = createCallbackFcn(app, @Detener_fuzzyButtonPushed, true);
            app.Detener_fuzzy.Text = 'Detener';
            app.Resultados_fuzzy = uitab(app.Fuzzy);
            app.Resultados_fuzzy.Title = 'Resultados';
            app.Errores_posicion_fuzzy = uiaxes(app.Resultados_fuzzy);
            title(app.Errores_posicion_fuzzy, 'Errores de Posicion')
            xlabel(app.Errores_posicion_fuzzy, 'Tiempo')
            ylabel(app.Errores_posicion_fuzzy, 'Errores')
            app.Errores_velocidad_fuzzy = uiaxes(app.Resultados_fuzzy);
            title(app.Errores_velocidad_fuzzy, 'Errores de Velocidad')
            xlabel(app.Errores_velocidad_fuzzy, 'Tiempo')
            ylabel(app.Errores_velocidad_fuzzy, 'Errores')
            app.Velocidades_reales_fuzzy = uiaxes(app.Resultados_fuzzy);
            title(app.Velocidades_reales_fuzzy, 'Velocidades reales y Control')
            xlabel(app.Velocidades_reales_fuzzy, 'Tiempo')
            app.Velocidades_dinamica_fuzzy = uiaxes(app.Resultados_fuzzy);
            title(app.Velocidades_dinamica_fuzzy, 'Velocidades Compensacion DInamica')
            xlabel(app.Velocidades_dinamica_fuzzy, 'Tiempo')
            app.Parametros_fuzzy = uitab(app.Fuzzy);
            app.Parametros_fuzzy.Title = 'Adaptacion';
            app.Adaptacion_proporcional_fuzzy = uiaxes(app.Parametros_fuzzy);
            title(app.Adaptacion_proporcional_fuzzy, 'Adaptacion Ganancias Proporcionales')
            xlabel(app.Adaptacion_proporcional_fuzzy, 'Tiempo')
            app.Adaptacion_derivativo_fuzzy = uiaxes(app.Parametros_fuzzy);
            title(app.Adaptacion_derivativo_fuzzy, 'Adaptacion Ganancias Derivativas')
            xlabel(app.Adaptacion_derivativo_fuzzy, 'Tiempo')
            app.OptimizacionTab = uitab(app.TabGroup);
            app.OptimizacionTab.Title = 'Optimizacion';
            app.Optimo = uitabgroup(app.OptimizacionTab);
            app.Ejecucion_optimo = uitab(app.Optimo);
            app.Ejecucion_optimo.Title = 'Ejecucion';
            app.ControlOptimoCompensacionDinamicaLabel = uilabel(app.Ejecucion_optimo);
            app.ControlOptimoCompensacionDinamicaLabel.Text = 'Control Optimo + Compensacion Dinamica';
            app.Movimiento_optimo = uiaxes(app.Ejecucion_optimo);
            title(app.Movimiento_optimo, 'Trayectoria Deseada y Trayectoria Descrita')
            xlabel(app.Movimiento_optimo, 'X')
            ylabel(app.Movimiento_optimo, 'Y')
            app.T_s_optimo = uieditfield(app.Ejecucion_optimo, 'numeric');
            app.T_s_optimo.Value = 0.1;
            app.SampleTimeLabel_3 = uilabel(app.Ejecucion_optimo);
            app.SampleTimeLabel_3.Text = 'Sample Time';
            app.Inicio_optimo = uibutton(app.Ejecucion_optimo, 'push');
            app.Inicio_optimo.ButtonPushedFcn = createCallbackFcn(app, @Inicio_optimoButtonPushed, true);
            app.Inicio_optimo.Text = 'Iniciar';
            app.FinalTimeEditFieldLabel_3 = uilabel(app.Ejecucion_optimo);
            app.FinalTimeEditFieldLabel_3.Text = 'Final Time';
            app.T_final_optimo = uieditfield(app.Ejecucion_optimo, 'numeric');
            app.DeseadoenXLabel_3 = uilabel(app.Ejecucion_optimo);
            app.DeseadoenXLabel_3.Text = 'Deseado en X';
            app.x_deseado_optimo = uieditfield(app.Ejecucion_optimo, 'text');
            app.DeseadoenYLabel_3 = uilabel(app.Ejecucion_optimo);
            app.DeseadoenYLabel_3.Text = 'Deseado en Y';
            app.y_deseado_optimo = uieditfield(app.Ejecucion_optimo, 'text');
            app.EstadoLabel_3 = uilabel(app.Ejecucion_optimo);
            app.EstadoLabel_3.Text = 'Estado';
            app.Estado_optimo = uilamp(app.Ejecucion_optimo);
            app.Detener_optimo = uibutton(app.Ejecucion_optimo, 'push');
            app.Detener_optimo.ButtonPushedFcn = createCallbackFcn(app, @Detener_optimoButtonPushed, true);
            app.Detener_optimo.Text = 'Detener';
            app.Resultados_optimo = uitab(app.Optimo);
            app.Resultados_optimo.Title = 'Resultados';
            app.Errores_posicion_optimo = uiaxes(app.Resultados_optimo);
            title(app.Errores_posicion_optimo, 'Errores de Posicion')
            xlabel(app.Errores_posicion_optimo, 'Tiempo')
            ylabel(app.Errores_posicion_optimo, 'Errores')
            app.Errores_velocidad_optimo = uiaxes(app.Resultados_optimo);
            title(app.Errores_velocidad_optimo, 'Errores de Velocidad')
            xlabel(app.Errores_velocidad_optimo, 'Tiempo')
            ylabel(app.Errores_velocidad_optimo, 'Errores')
            app.Velocidades_reales_optimo = uiaxes(app.Resultados_optimo);
            title(app.Velocidades_reales_optimo, 'Velocidades reales y Control')
            xlabel(app.Velocidades_reales_optimo, 'Tiempo')
            app.Velocidades_dinamica_optimo = uiaxes(app.Resultados_optimo);
            title(app.Velocidades_dinamica_optimo, 'Velocidades Compensacion DInamica')
            xlabel(app.Velocidades_dinamica_optimo, 'Tiempo')
            app.NeuronalTab = uitab(app.TabGroup);
            app.NeuronalTab.Title = 'Neuronal';
            app.EvasiondeObstaculosTab = uitab(app.TabGroup);
            app.EvasiondeObstaculosTab.Title = 'Evasion de Obstaculos';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PRUEBA_INTERFACE_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end